classdef OptionTradingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        axPulsePlot                    matlab.ui.control.UIAxes
        btnPlot                        matlab.ui.control.Button
        swAutoUpdate                   matlab.ui.control.Switch
        lblAutoUpdate                  matlab.ui.control.Label
        lblDispersion                  matlab.ui.control.Label
        lblModulation                  matlab.ui.control.Label
        lblHighPass                    matlab.ui.control.Label
        lblWindow                      matlab.ui.control.Label
        lblLowPass                     matlab.ui.control.Label
        lblEdge                        matlab.ui.control.Label
        ddType                         matlab.ui.control.DropDown
        lblType                        matlab.ui.control.Label
        lblLowPass_2                   matlab.ui.control.Label
        lblLowPass_3                   matlab.ui.control.Label
        IntelligentOptionTradingLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        TRAININGTab                    matlab.ui.container.Tab
        TRAININGINFORMATIONLabel       matlab.ui.control.Label
        RULESEditFieldLabel            matlab.ui.control.Label
        RULESEditField                 matlab.ui.control.NumericEditField
        INPUTCLUSTERSEditFieldLabel    matlab.ui.control.Label
        INPUTCLUSTERSEditField         matlab.ui.control.NumericEditField
        OUTPUTCLUSTERSEditFieldLabel   matlab.ui.control.Label
        OUTPUTCLUSTERSEditField        matlab.ui.control.NumericEditField
        EVALUATETab                    matlab.ui.container.Tab
        EVALUATINGINFORMATIONLabel     matlab.ui.control.Label
        MSEEditFieldLabel              matlab.ui.control.Label
        MSEEditField                   matlab.ui.control.EditField
        RMSEEditFieldLabel             matlab.ui.control.Label
        RMSEEditField                  matlab.ui.control.EditField
        REditFieldLabel                matlab.ui.control.Label
        REditField                     matlab.ui.control.EditField
        TRADINGTab                     matlab.ui.container.Tab
        TRADINGRESULTSLabel            matlab.ui.control.Label
        AVERAGEPROFITLOSSEditFieldLabel  matlab.ui.control.Label
        AVERAGEPROFITLOSSEditField     matlab.ui.control.EditField
        OVERALLPROFITLOSSEditFieldLabel  matlab.ui.control.Label
        OVERALLPROFITLOSSEditField     matlab.ui.control.NumericEditField
        NUMBEROFTRADESEditFieldLabel   matlab.ui.control.Label
        NUMBEROFTRADESEditField        matlab.ui.control.NumericEditField
        DAILYRETURNEditFieldLabel      matlab.ui.control.Label
        DAILYRETURNEditField           matlab.ui.control.NumericEditField
        INTERPRETTab                   matlab.ui.container.Tab
        INTERPREATEDRULESLabel         matlab.ui.control.Label
        MODEButtonGroup                matlab.ui.container.ButtonGroup
        BenchmarkButton                matlab.ui.control.RadioButton
        OptionTradingButton            matlab.ui.control.RadioButton
        StockTradingButton             matlab.ui.control.RadioButton
        lmpAutoUpdate_2                matlab.ui.control.Lamp
        swAutoUpdate_2                 matlab.ui.control.Switch
        lblAutoUpdate_2                matlab.ui.control.Label
        INDSpinnerLabel                matlab.ui.control.Label
        INDSpinner                     matlab.ui.control.Spinner
        OUTDSpinnerLabel               matlab.ui.control.Label
        OUTDSpinner                    matlab.ui.control.Spinner
        cknWindow_2                    matlab.ui.control.Knob
        cknHighPass                    matlab.ui.control.Knob
        cknWindow                      matlab.ui.control.Knob
        cknLowPass                     matlab.ui.control.Knob
        cknEdge                        matlab.ui.control.Knob
        cknLowPass_2                   matlab.ui.control.Knob
        cknLowPass_3                   matlab.ui.control.Knob
        DefaultConfigurationButton     matlab.ui.control.StateButton
        cknHighPass_2                  matlab.ui.control.Knob
        cknLowPass_4                   matlab.ui.control.Knob
        lblLowPass_4                   matlab.ui.control.Label
        DatasetEditFieldLabel          matlab.ui.control.Label
        DatasetEditField               matlab.ui.control.EditField
        OptionDatasetEditFieldLabel    matlab.ui.control.Label
        OptionDatasetEditField         matlab.ui.control.EditField
        lmpAutoUpdate_3                matlab.ui.control.Lamp
        lmpAutoUpdate_4                matlab.ui.control.Lamp
        swAutoUpdate_3                 matlab.ui.control.Switch
        lblAutoUpdate_3                matlab.ui.control.Label
    end

    
    properties (Access = private)
        alpha;
        beta;
        eta;
        ff;
        lamda;
        rate;
        omega;
        gamma;
        epoch;
        interpolation;
        episodic;
        IND;
        OUTD;
        indata;
        outdata;
        data;
        optiondata;
        predicted;
        datasize;
        Sigma0 = sqrt(0.16);
        forget = 1;
        tau = 0.2;
        filename;
    end
    
    methods (Access = public)
        function net_out = Run_SaFIN(choice, TrainData,TestData,IND,OUTD, Epochs,Eta,Sigma0,Forgetfactor, forget,Lamda,tau, Rate,Omega,Gamma, varargin)
            
            %IND = size(TrainData_IN,2); OUTD = size(TrainData_OUT,2);
            %TrainData = [TrainData_IN TrainData_OUT];
            %clear TrainData_IN;
            %clear TrainData_OUT;
            
            % ---manan
            
            if isempty(varargin)
                numSamples = size(TrainData,1);
            else
                numSamples = varargin{1};
            end
            %INITIALIZE THE SYSTEM
            system.total_network = 1;
            system.net.name = 'SAFIN(FRIE)++';
            system.net.Epochs = Epochs;
            system.net.Eta = Eta;
            system.net.Sigma0 = Sigma0; %SMALL PERTURBATION
            system.net.Rate = Rate; %LEARNING RATE
            system.net.Forgetfactor = Forgetfactor; %FORGETTING FACTOR
            system.net.Lamda = Lamda; %THRESHOLD FOR INTERPOLATION
            system.net.Omega = Omega;
            system.net.Gamma = Gamma;
            system.net.interpolated = zeros(1,size(TrainData,1));
            system.net.ruleCount = zeros(size(TrainData,1),1);
            net = system.net;
            %INITIALIZE NEURAL NET WITH FIRST ROW OF DATA
            net = SAFIN_init(net, TrainData(1,1:IND), TrainData(1,IND+1:IND+OUTD), Sigma0);
            %%%% 3 WAYS TO USE MODEL
            % 1. ONLINE TRAINING FOLLOWED BY TESTING - TRAIN ONCE THROUGH TRAINING SET FOLLOWED BY TESTING
            % 2. ONLINE TRAINING AND TESTING - TRAIN ON THE FIRST DAT AND TEST ON SECOND DATA, TRAIN ON SECOND DATA, TEST ON THIRD DATA
            % 3. OFFLINE TRAINING FOLLOWED BY TESTING - TRAIN EPOCH TIMES THROUGH TRAIN DATA AND TEST ON TESTING DATA
            switch choice
                case 1
                    for i = 1 : size(TrainData, 1)
                        disp('Progress: ');
                        disp(i/size(TrainData,1)*100); %DISPLAY PROGRESS OF TRAINING
                        %TRAINING THE NET
                        net = SaFIN_train(i, net, TrainData(i,1:IND), TrainData(i,IND+1:IND+OUTD),IND, OUTD, net.no_InTerms, net.InTerms, net.no_OutTerms, net.OutTerms,net.Rules, net.Rules_Weight, Eta, Forgetfactor,Lamda, Rate, Omega, Gamma);
                        disp('Number of rules: ');
                        disp(size(net.Rules,1));
                        ruleCount(i,1) = size(net.Rules,1);
                    end
                    net_out = zeros(size(TestData,1), OUTD);
                    net.rule_importance = zeros(size(net.Rules,1),1);
                    for i = 1 : size(TestData,1)
                        net_out(i,:) = SaFIN_test(TestData(i, 1:IND),IND,OUTD,net.no_InTerms,net.InTerms,net.no_OutTerms,net.OutTerms,net.Rules);
                    end
                case 2
                    net_out = zeros(size(TestData,1), OUTD);  %PREDICTED VALUES
                    %net.interpolation_results = zeros(size(TestData,1),1); %INTERPOLATED POINTS
                    for i = 1 : size(TrainData,1)
                        disp('Progress: ');
                        disp(i/size(TrainData,1)*100);  %DISPLAY PROGRESS OF TRAINING
                        %PASS I-TH ROW TO TRAIN
                        net = SaFIN_train(i, net, TrainData(i,1:IND), TrainData(i,IND+1:IND+OUTD), IND,OUTD, net.no_InTerms, net.InTerms, net.no_OutTerms, net.OutTerms,net.Rules, net.Rules_Weight, Eta, Forgetfactor,Lamda, Rate, Omega, Gamma);
                        disp('Number of rules: ');
                        disp(size(net.Rules,1));
                        ruleCount(i,1) = size(net.Rules,1);
                        if (i+1) <= size(TestData,1)
                            %PASS (I+1)-TH ROW TO TEST
                            net_out(i+1,:) = SaFIN_test(TestData(i+1,1:IND), IND, OUTD, net.no_InTerms,net.InTerms,net.no_OutTerms,net.OutTerms,net.Rules);
                        end
                    end
                    net_out = net_out(2:size(net_out,1),:); %PASSING NET_OUT TO PLOT
                    TestData = TestData(2:size(TestData,1),:); %PASSING TEST DATA TO PLOT
                case 3
                    train = [];
                    for n = 1 : Epochs
                        train = [train; TrainData];
                    end
                    TrainData = train; %epoch * train_data
                    clear train;
                    for i = 1 : size(TrainData,1)
                        disp('Progress: ');
                        disp(i/size(TrainData,1)*100); %DISPLAY TRAINING PROGRESS
                        net = SaFIN_train(i, net, TrainData(i,1:IND), TrainData(i,IND+1:IND+OUTD), IND, OUTD, net.no_InTerms, net.InTerms, net.no_OutTerms, net.OutTerms,net.Rules, net.Rules_Weight,Eta, Forgetfactor,Lamda, Rate, Omega, Gamma);
                        disp('Number of rules: ');
                        disp(size(net.Rules,1));
                        ruleCount(i,1) = size(net.Rules,1);
                    end
                    net_out = zeros(size(TestData,1), OUTD); %
                    for i = 1:size(TestData,1)
                        net_out(i,:) = SaFIN_test(TestData(i,1:IND), IND, OUTD, net.no_InTerms,net.InTerms,net.no_OutTerms,net.OutTerms,net.Rules);
                    end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% DISPLAY INPUT AND OUTPUT CLUSTERS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for i = 1:IND
                colorArray = 'bgrcmyk';
                figure;
                hold;
                str = [sprintf('Input %d',i)];
                title(str);
                for j = 1:net.no_InTerms(i)
                    color = colorArray(mod(j,7)+1);
                    x = [min(TrainData(:,i)):0.05:max(TrainData(:,i))];
                    plot(x, gaussmf(x, [net.InTerms(i,2*j)/sqrt(2) net.InTerms(i,2*j-1)]), color);
                    axis([min(TrainData(:,i)) max(TrainData(:,i)) 0 1]);
                end
            end
            for i = 1:OUTD
                colorArray = 'bgrcmyk';
                figure;
                hold;
                str = [sprintf('Output %d',i)];
                title(str);
                for j = 1:net.no_OutTerms(i)
                    color = colorArray(mod(j,7)+1);
                    x = [min(TrainData(:,IND+i)):0.05:max(TrainData(:,IND+i))];
                    plot(x, gaussmf(x, [net.OutTerms(i,2*j)/sqrt(2) net.OutTerms(i,2*j-1)]), color);
                    axis([min(TrainData(:,IND+i)) max(TrainData(:,IND+i)) 0 1]);
                end
            end
            %%%%%%%%%%%%%%%% INTERPRET FEATURES%%%%%%%%%%%%%%%%%
            interpret(net, IND, OUTD);
            %%%%%%%%%DISPLAY PREDICTION VS ACTUAL DATA %%%%%%%%%%%%%%%%%%%
            for i = 1:OUTD
                max_output = max(TestData(:,IND+i));
                min_output = min(TestData(:,IND+i));
                unit = (max_output - min_output) / 5; %UNIT FOR PLOTTING INTERPOLATION INDICATOR
                figure; hold;
                str = [sprintf('Actual VS Predicted Data',i)]; title(str);
                plot(1:1:size(TestData,1),TestData(:,i+IND),'b'); %PLOT ACTUAL DATA
                plot(1:1:size(TestData,1),net_out(:,i),'r');      %PLOT PREDICTED DATA
                plot(1:1:size(TrainData,1), unit*net.interpolated(1,:),'g'); %INDICATE INTERPOLATED POINTS
                %plot(1:1:size(TrainData,1), ruleCount(:,1),'m')
                legend('Actual','Predicted', 'Interpolated/Extrapolated Points', '# Rules Generated');
            end
            for i = 1:OUTD
                figure; hold;
                str = [sprintf('Number of rules generated',i)]; title(str);
                plot(1:1:size(TrainData,1), ruleCount(:,1),'m')
                legend('Actual','Predicted', 'Interpolated/Extrapolated Points', '# Rules Generated');
            end
            %%%%%%%%%%%%%%%%%%%MEAN SQUARE ERROR
            MSE = 0;
            for i = 1:size(TestData,1)
                MSE = MSE+(TestData(i,IND+1)-net_out(i))^2;
            end
            MSE = 1/size(TestData,1)*MSE;
            X = ['MSE = ', num2str(MSE)];
            disp(X);
            %%%%%%%%%%%%%%%%%%%PEARSON CORRELATION COEFFECIENT R
            R = 0;
            mean_desired = mean(TestData(:,IND+1));
            mean_computed = mean(net_out(:));
            top_R2 = 0;
            bottom_R2_1 = 0;
            bottom_R2_2 = 0;
            for i = 1:size(TestData,1)
                top_R2 = top_R2 + (TestData(i,IND+1) - mean_desired)*(net_out(i) - mean_computed);
                bottom_R2_1 = bottom_R2_1 + (TestData(i,IND+1) - mean_desired)^2;
                bottom_R2_2 = bottom_R2_2 + (net_out(i) - mean_computed)^2;
            end
            R = (top_R2)/(sqrt(bottom_R2_1)*sqrt(bottom_R2_2));
            X = ['R= ', num2str(R)];
            disp(X);
            %%%%%%%NUMBER OF INTERPOLATED/ EXTRAPOLATED POINTS%%%%%%%%
            X = ['Number of interpolated/ extrapolated points = ', num2str(net.interpolateCount)];
            disp(X);
            %STORE TO SYSTEM
            system.MSE = MSE;
            system.R = R;
            system.net.interpolateCount = net.interpolateCount;
        end
        %%%%%%%%%%%%%%%%%%%%%%% 1.intialisation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function net = SAFIN_init(net,input,output,sigma) %
            IND = size(input,2);  %SIZE OF INPUT
            OUTD = size(output,2); %SIZE OF OUTPUT
            no_InTerms = zeros(IND,1);  %IND x 1 MATRIX
            InTerms = zeros(IND,2); %IND X 2 MATRIX: 1ST FOR CENTRE, 2ND FOR SPREAD
            no_OutTerms = zeros(OUTD,1); %OUTD X 1 MATRIX
            OutTerms = zeros(OUTD, 2); %OUTD X 2 MATRIX: 1ST FOR CENTRE, 2ND FOR SPREAD
            Rules = zeros(1,IND+OUTD); % 1 x (IND + OUTD) MATRIX
            Rules_Weight = zeros(1,1); % 1 X 1 MATRIX
            for dim = 1 : IND          %FOR EACH DIMENSION
                no_InTerms(dim,1) = 1; %INITIALIZE FIRST CLUSTER NAME
                InTerms(dim ,1) = input(1,dim); %FIRST CENTRE = FIRST DATA POINT
                InTerms(dim,2) = sigma; %FIRST SPREAD = SIGMA = SMALL PERTURBATION
            end
            for dim = 1 : OUTD        
                no_OutTerms(dim,1) = 1;          %INTIALIZE FIRST CLUSTER NAME
                OutTerms(dim,1) = output(1,dim); %FIRST CENTRE = FIRST DATA POINT
                OutTerms(dim,2) = sigma;         %SPREAD = SMALL PERTURBATION
            end
            for dim = 1 : (IND+OUTD)
                Rules(1,dim) = 1;               %INITIALIZE FIRST RULE WITH FIRST CLUSTERS
            end
            %INITIALIZE RULES WEIGHT
            Rules_Weight(1,1) = 1;
            %STORE INITIALIZED SYSTEM INTO SYSTEM IMAGE
            net.no_InTerms = no_InTerms;
            net.InTerms = InTerms;
            net.no_OutTerms = no_OutTerms;
            net.OutTerms = OutTerms;
            net.Rules = Rules;
            net.Rules_Weight = Rules_Weight;
            net.interpolateCount = 0;
            net.ruleNumber = 1;
        end
        
        function net = SaFIN_train(data_point, net, input, output, IND,OUTD, no_InTerms, InTerms, no_OutTerms, OutTerms,Rules, Rules_Weight,Eta,Forgetfactor,Lamda,Rate, Omega, Gamma)
            thre_lam = 0.2*Lamda;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%1. Rule generation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            in_mf = zeros(IND, max(no_InTerms));
            for dim = 1 : IND
                for term = 1 : no_InTerms(dim)
                    in_mf(dim,term) = exp(-(input(1,dim) - InTerms(dim, 2*term-1))^2 / (InTerms(dim, 2*term)^2));
                end
            end
            act_rules_forward = zeros(size(Rules,1),1);
            forward_activation = zeros(size(Rules,1),1);
            for rule = 1 : size(Rules,1) %for each rule
                tmp_forwad = zeros(IND,1);
                for dim = 1 : IND %for each dimension
                    tmp_forward(dim) = in_mf(dim, Rules(rule,dim)); %get the similarity value of that dimension
                end
                [c, index] =  min(tmp_forward); %get minimum similarity value of every dimension for that rule
                act_rules_forward(rule,1) = in_mf(index, Rules(rule,index));
                forward_activation(rule) = c;
                %disp('Act_rules_forward:')
                %disp(act_rules_forward);
                clear c, clear index;
            end
            out_mf = zeros(OUTD, max(no_OutTerms));
            for dim = 1:OUTD
                for term = 1 : no_OutTerms(dim)
                    out_mf(dim,term) = exp(-(output(1,dim) - OutTerms(dim,2*term-1))^2 /(OutTerms(dim,2*term)^2));
                end
            end
            act_rules_backward = zeros(size(Rules,1),1);
            backward_activation = zeros(size(Rules,1),1);
            for rule = 1 : size(Rules,1)
                tmp_backward = zeros(OUTD,1);
                for dim = 1:OUTD
                    tmp_backward(dim) = out_mf(dim,Rules(rule,IND+dim));
                end
                [c,index] = min(tmp_backward);
                act_rules_backward(rule,1) = out_mf(index, Rules(rule,IND+index));
                backward_activation(rule) = c;
                %disp('Act_rules_backward:')
                %disp(act_rules_backward);
                clear c, clear index;
            end
            rule_activation = zeros(size(Rules,1),1);
            for rule = 1 : size(Rules,1)
                rule_activation(rule) = min([forward_activation(rule); backward_activation(rule)]);
            end
            if(max(rule_activation) > Lamda)
                [c, index] = max(rule_activation);
                for rule = 1 : size(Rules,1)
                    if (rule == index)
                        Rules_Weight(rule,1) =  Rules_Weight(rule,1) + 1;
                    else
                        Rules_Weight(rule,1) =  max([Forgetfactor*Rules_Weight(rule); rule_activation(rule)]);
                    end
                end
                clear c; clear index;
            end
            %%%%%%%%%%%%%%%% interpolate step 1 %%%%%%%%%%%%%%%
            for dim = 1 : IND
                for term = 1 : no_InTerms(dim)
                    rep_A(dim,term) = InTerms(dim,2*term-1); %representative value is the centre of the fuzzy cluster
                    c_A(dim,term) = InTerms(dim, 2*term-1);
                    sig_A(dim,term) = InTerms(dim, 2*term);
                end
            end
            for rule = 1:size(Rules,1)
                for dim = 1 : IND
                    rep_A_k_j(rule,dim) = rep_A(dim, Rules(rule,dim));
                    c_A_k_j(rule,dim) = c_A(dim, Rules(rule,dim));
                    sig_A_k_j(rule,dim) = sig_A(dim, Rules(rule,dim));
                end
            end
            for dim = 1 : OUTD
                for term = 1 : no_OutTerms(dim)
                    c_B(dim,term) = OutTerms(dim, 2*term-1);
                    sig_B(dim,term) = OutTerms(dim,2*term);
                end
            end
            for rule = 1 : size(Rules,1)
                for dim = 1 : OUTD
                    c_B_k_j(rule,dim) = c_B(dim, Rules(rule,IND+dim));
                    sig_B_k_j(rule,dim) = sig_B(dim, Rules(rule, IND+dim));
                end
            end
            net.rep_A = rep_A;
            net.c_A = c_A;
            net.sig_A = sig_A;
            net.rep_A_k_j = rep_A_k_j;
            net.c_A_k_j = c_A_k_j;
            net.sig_A_k_j = sig_A_k_j;
            net.c_B_k_j = c_B_k_j;
            net.sig_B_k_j = sig_B_k_j;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % If all existing rules are unable to cover input and output satisfactorily
            if(max(rule_activation) <= Lamda) %create a new rule %1 == create new MF %2 == update existing MF 3== do nothing % 4 == interpolate
                new_rule = zeros(1, IND+OUTD);
                new_rule_action = zeros(IND+OUTD,1);
                for dim = 1 : IND
                    in_mfv = zeros(no_InTerms(dim),1);
                    for term = 1 : no_InTerms(dim)
                        in_mfv(term) = in_mf(dim,term);
                    end
                    [max_antecedent, index_a] = max(in_mfv);
                    %interpolate step 1
                    net.rep_ob_A(dim) = InTerms(dim, 2*index_a - 1);
                    net.ob_A_c(1,dim) = InTerms(dim, 2*index_a - 1);
                    net.ob_A_sig(1,dim) = InTerms(dim,2*index_a);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if(max_antecedent > Lamda)
                        new_rule(dim) = index_a;
                        new_rule_action(dim) = 3; %do nothing
                    else
                        %increase the sigma to see if it is satisfied
                        c = InTerms(dim,2*index_a - 1);
                        sigma = InTerms(dim, 2*index_a);
                        sigma_new = sigma + Rate*(1-max_antecedent)*sigma;
                        new_membership = exp(-(input(1,dim) - c)^2 / (sigma_new^2));
                        if(new_membership > Lamda)
                            new_rule(dim) = index_a;
                            new_rule_action(dim) = 2;
                        end
                        if (max_antecedent > (thre_lam)) && (new_rule_action(dim) == 0)
                            new_rule(dim) = no_InTerms(dim) + 1; %interpolate new fuzzy term
                            new_rule_action(dim) = 4;
                        end
                        if new_rule_action(dim) == 0
                            new_rule(dim) = no_InTerms(dim)+1; %create new fuzzy term
                            new_rule_action(dim) = 1;
                        end
                        clear centre, clear sigma, clear sigma_new; clear in_mfv, clear max_antecednet, clear index_max; clear new_membership;
                    end
                end
                for dim = 1 : OUTD
                    out_mfv = zeros(no_OutTerms(dim),1);
                    for term = 1 : no_OutTerms(dim)
                        out_mfv(term) = out_mf(dim,term);
                    end
                    [max_consequent, max_index] = max(out_mfv);
                    if(max_consequent > Lamda)
                        new_rule(IND+dim) = max_index;
                        new_rule_action(IND+dim) = 3;
                    else
                        c = OutTerms(dim,2*max_index-1);
                        sigma = OutTerms(dim, 2*max_index);
                        sigma_new = sigma + Rate*(1-max_consequent)*sigma;
                        new_membership = exp(-(output(1,dim) - c)^2 / (sigma_new^2));
                        if(new_membership > Lamda)
                            new_rule(IND+dim) = max_index;
                            new_rule_action(IND+dim) = 2;
                        end
                        if(max_consequent > (thre_lam) && new_rule_action(dim) == 0)
                            new_rule(IND+dim) = no_OutTerms(dim)+1;
                            new_rule_action(IND+dim) = 4;
                        end
                        if(new_rule_action(IND+dim) == 0)
                            new_rule(IND+dim) = no_OutTerms(dim) + 1;
                            new_rule_action(IND+dim) = 1;
                        end
                        clear c, clear sigma, clear sigma_new, clear out_mfv, clear max_consequent, clear max_index; clear new_membership;
                    end
                end
                %%%%%%%%%%check whether the new rule is ambiguous%%%%%%%
                ambiguous = 0;
                for rule = 1 : size(Rules,1)
                    if Rules(rule, 1:IND) == new_rule(1, 1:IND)
                        for dim = 1 : OUTD
                            if(Rules(rule, IND+dim) ~= new_rule(1,IND+dim))
                                ambiguous = 1;
                            end
                        end
                    end
                end
                if ambiguous == 0
                    %check novelty
                    novel = 1;
                    for rule = 1 : size(Rules,1)
                        if(Rules(rule,:) == new_rule(1,:))
                            novel = 0;
                            index_novel = rule;
                            break;
                        end
                    end
                    %insert rule if novel
                    if novel == 1
                        Rules = [Rules; new_rule]; %add new rule
                        Rules_Weight = [Rules_Weight; 1]; %add new weight
                        index_novel = size(Rules,1);
                    end
                    %create/ update new mf
                    for dim = 1 : IND
                        if new_rule_action(dim) == 1 %create new mf
                            [left_n, right_n, sigma] = neighbours(InTerms(dim,:),input(1,dim));
                            no_InTerms(dim) = no_InTerms(dim)+1;
                            [max_labels, index_max] = max(no_InTerms);
                            if dim == index_max
                                InTerms = [InTerms, zeros(IND, 2)];
                            end
                            %update
                            InTerms(dim,2*no_InTerms(dim)-1) = input(1,dim);
                            InTerms(dim,2*no_InTerms(dim)) = sigma;
                            %update neighbours
                            if(left_n ~= 0)
                                InTerms(dim,2*left_n) = sigma;
                            end
                            if (right_n ~= 0)
                                InTerms(dim,2*right_n) = sigma;
                            end
                        else
                            if (new_rule_action(dim,1) == 2)
                                sigma = InTerms(dim,2*new_rule(1,dim));
                                sigma = sigma + Rate*(1 - in_mf(dim, new_rule(dim)))*sigma;
                                InTerms(dim, 2*new_rule(dim)) = sigma;
                                clear sigma;
                            else
                                if new_rule_action(dim) == 4
                                    %%%%%%%%%%%%% interpolate step 2 intermediate rules %%%%%%%%%%%%
                                    net.interpolateCount = net.interpolateCount + 1;
                                    net.interpolated(1,data_point) = 1;
                                    disp('interpolated.....');
                                    [left_n, right_n, sigma] = neighbours(InTerms(dim,:), input(1,dim));
                                    %get the left and righ neigbour of observation
                                    if left_n == 0
                                        rep_left(dim) = 0;
                                    else
                                        rep_left(dim) = InTerms(dim,2*left_n-1); %centre of the fuzzy
                                    end
                                    if right_n == 0
                                        rep_right(dim) = 0;
                                    else
                                        rep_right(dim) = InTerms(dim,2*right_n-1);   %centre of the fuzzy
                                    end
                                    %calculate weight of the closest fuzzy rule
                                    net.w = zeros(size(net.Rules,1)-1,IND);
                                    for k = 1 : (size(net.Rules,1)-1)
                                        %for dim = 1 :IND
                                        net.w(k,dim) = 1 - abs( (net.rep_ob_A(1,dim) - rep_A_k_j(k,dim) /(rep_left(dim)-rep_right(dim))));
                                        %end
                                    end
                                    net.weight = zeros(size(net.w,1),1);
                                    for rule = 1 : size(net.w,1)
                                        net.weight(k,1) = min(net.w(rule,:));
                                    end
                                    %disp(net.weight);
                                    net.sum_w = sum(net.weight(:,1));
                                    %disp(net.sum_w);
                                    %normalise the rule weight
                                    net.nor_weight = zeros(size(net.weight,1),1);
                                    for rule = 1 : size(net.weight,1)
                                        net.nor_weight(rule,1) = net.weight(rule,1) / net.sum_w;
                                    end
                                    %disp(net.nor_weight);
                                    %%%%%%%%%%%%% interpolate step 3 intermediate rules %%%%%%%%%%%%
                                    size_ = min(size(net.nor_weight,1), size(net.c_A_k_j));
                                    net.inter_A_centre = net.nor_weight(1:size_,:)'*net.c_A_k_j(1:size_,:);
                                    net.inter_A_sigma = net.nor_weight(1:size_,:)'*net.sig_A_k_j(1:size_,:);
                                    net.inter_B_centre = net.nor_weight(1:size_,:)'*net.sig_A_k_j(1:size_,:);
                                    net.inter_B_sigma = net.nor_weight(1:size_,:)'*net.sig_A_k_j(1:size_,:);
                                    %%%%%%%%%%%%intepolate step 4 %%%%%%%%%%%%%%%%%%%%%%%%%%
                                    net.ob_B_c = net.inter_B_centre;
                                    %disp(net.ob_B_centre);
                                    net.ob_B_sig = net.inter_B_sigma* (sum(net.ob_A_sig) / sum(net.inter_A_sigma));
                                    %disp(net.ob_B_sigma);
                                    %%%%%%%%%%%% add to clusters %%%%%%%%%%%%%%%%%%%%%%%%%%
                                    no_InTerms(dim) = no_InTerms(dim) + 1;
                                    [max_labels, index_labels] = max(no_InTerms);
                                    if dim == index_labels
                                        InTerms = [InTerms, zeros(IND,2)];
                                    end
                                    %add new centre and sigma
                                    InTerms(dim, 2*no_InTerms(dim) -1) = net.ob_A_c(1,dim);
                                    InTerms(dim, 2*no_InTerms(dim)) = net.ob_A_sig(1,dim);
                                    %update neighbour
                                    if left_n ~= 0
                                        InTerms(dim,2*left_n) = net.ob_A_sig(1,dim);
                                    end
                                    if right_n ~= 0
                                        InTerms(dim,2*right_n) = net.ob_A_sig(1,dim);
                                    end
                                end
                            end
                        end
                    end
                    for dim = 1 : OUTD
                        if new_rule_action(IND+dim) == 1 %create new membership function
                            [left_n, right_n, sigma] = neighbours(OutTerms(dim,:),output(1,dim));
                            no_OutTerms(dim) = no_OutTerms(dim)+1;
                            [max_labels, index_labels] = max(no_OutTerms);
                            if(dim == index_labels)
                                OutTerms = [OutTerms, zeros(OUTD,2)];
                            end
                            OutTerms(dim,2*no_OutTerms(dim)-1) = output(1,dim);
                            OutTerms(dim,2*no_OutTerms(dim)) = sigma;
                            if(left_n ~= 0)
                                OutTerms(dim,2*left_n) = sigma;
                            end
                            if(right_n ~= 0)
                                OutTerms(dim, 2*right_n) = sigma;
                            end
                            clear left_n; clear right_n; clear max_labels; clear index_labels;
                        else
                            if(new_rule_action(IND+dim) == 2)
                                sigma = OutTerms(dim, 2*new_rule(IND+dim));
                                new_sigma = sigma + Rate*(1 - out_mf(dim,new_rule(IND+dim)))*sigma;
                                OutTerms(dim, 2*new_rule(IND+dim)) = new_sigma;
                                clear sigma;
                            else
                                if(new_rule_action(IND+dim) == 4)
                                    [left_n, right_n, sigma] = neighbours(OutTerms(dim,:), output(1,dim));
                                    no_OutTerms(dim) = no_OutTerms(dim) + 1;
                                    [max_labels, index_labels] = max(no_OutTerms);
                                    if dim == index_labels
                                        OutTerms = [OutTerms, zeros(OUTD, 2)];
                                    end
                                    %add new interpolated value
                                    OutTerms(dim,2*no_OutTerms(dim)-1) = net.ob_B_c(1,dim);
                                    OutTerms(dim,2*no_OutTerms(dim)) = net.ob_B_sig(1,dim);
                                    %update neigbohbour
                                    if left_n ~= 0
                                        OutTerms(dim,2*left_n) = net.ob_B_sig(1,dim);
                                    end
                                    if right_n ~= 0
                                        OutTemrs(dim,2*right_n) = net.ob_B_sig(1,dim);
                                    end
                                    clear left_n, clear right_n, clear max_labels, clear index_labels;
                                end
                            end
                        end
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% done update clusters%%%%%%%%%%%
                %calculate forward activation again
                in_mf = zeros(IND, max(no_InTerms));
                for dim = 1 : IND
                    for term = 1 : no_InTerms(dim)
                        in_mf(dim,term) = exp( -(input(1,dim)-InTerms(dim,2*term-1))^2 / (InTerms(dim,2*term)^2) );
                    end
                end
                act_rules_forward = zeros(size(Rules,1),1);
                forward_activation = zeros(size(Rules,1),1);
                for rule = 1 : size(Rules,1)
                    tmp_forward = zeros(IND,1);
                    for dim = 1 : IND
                        tmp_forward(dim) = in_mf(dim,Rules(rule,dim));
                    end
                    [c,index] = min(tmp_forward);
                    act_rules_forward(rule,1) = in_mf(index,Rules(rule,index));
                    forward_activation(rule) = c;
                    clear c, clear index
                end
                clear tmp_forward;
                out_mf = zeros(OUTD, max(no_OutTerms));
                for dim = 1 : OUTD
                    for term = 1 : no_OutTerms(dim)
                        out_mf(dim, term) = exp( -(output(1,dim)-OutTerms(dim,2*term-1))^2 / (OutTerms(dim,2*term)^2) );
                    end
                end
                act_rules_backward = zeros(size(Rules,1),1);
                backward_activation = zeros(size(Rules,1),1);
                for rule = 1 : size(Rules,1)
                    tmp_backward = zeros(OUTD,1);
                    for dim = 1 : OUTD
                        tmp_backward = out_mf(dim, Rules(rule, IND+dim));
                    end
                    [c, index] = min(tmp_backward);
                    act_rules_backward(rule,1) = out_mf(index, Rules(rule, IND+index));
                    backward_activation(rule) = c;
                    clear c; clear index;
                end
                clear tmp_backward;
                %calcualte rule activation
                rule_activation = zeros(size(Rules,1),1);
                for rule = 1 : size(Rules,1)
                    rule_activtion(rule) = min([forward_activation(rule); backward_activation(rule)]);
                end
                for rule = 1 : size(Rules,1)
                    if ambiguous == 1
                        Rules_Weight(rule) = max([Forgetfactor*Rules_Weight(rule); rule_activation(rule)]);
                    else
                        if rule ~= index_novel
                            Rules_Weight(rule) = max([Forgetfactor*Rules_Weight(rule); rule_activation(rule)]);
                        end
                    end
                end
            end
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % %%%%% 2. Merging of MFs
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % for dim = 1:OUTD
            %     [index_1, index_2, similarity] = most_similar(no_OutTerms(dim), OutTerms(dim,:));
            %     if(similarity >= Omega)
            %         for rule = 1 : size(Rules,1)
            %             %update rule
            %             if( Rules(rule, IND+dim) == index_2 )
            %                 Rules(rule, IND+dim) = index_1;
            %             end
            %             if( Rules(rule, IND+dim) > index_2 )
            %                 Rules(rule, IND+dim) = Rules(rule, IND+dim) - 1;
            %             end
            %         end
            %         [Rules, Rules_Weight] = check_repeat_rules(Rules, Rules_Weight); %check repeat rule
            %         [no_OutTerms, OutTerms] = merge_MF(no_OutTerms, OutTerms, dim, index_1, index_2); %merge clusters
            %     end
            %     clear index_1; clear index_2; clear similarity;
            % end
            % for dim = 1 : IND
            %     [index_1, index_2, similarity] = most_similar(no_InTerms(dim), InTerms(dim,:));
            %     if similarity >= Omega
            %         for rule = 1 : size(Rules,1)
            %             if( Rules(rule,dim) == index_2 )
            %                 Rules(rule,dim) = index_1;
            %             end
            %             if( Rules(rule,dim) > index_2 )
            %                 Rules(rule,dim) = Rules(rule,dim) - 1;
            %             end
            %         end
            %         [Rules, Rules_Weight] = check_repeat_rules(Rules, Rules_Weight);
            %         [no_InTerms, InTerms] = merge_MF(no_InTerms, InTerms, dim, index_1, index_2);
            %     end
            %     clear index_1; clear index_2; clear similarity;
            % end
            % [Rules, Rules_Weight] = check_ambiguous_rules(Rules, Rules_Weight, IND, OUTD);
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % %%%% 3. Delete obsolete rule (max one rule per epoch)
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % new_Rules = []; new_Rules_Weight = [];
            % %delete rule with rule weight < 0.1
            % for rule = 1 : size(Rules,1)
            %     if Rules_Weight(rule) >= Gamma
            %         new_Rules = [new_Rules; Rules(rule,:)];
            %         new_Rules_Weight = [new_Rules_Weight; Rules_Weight(rule)];
            %     end
            % end
            % Rules = new_Rules;
            % Rules_Weight = new_Rules_Weight;
            % clear new_Rules; clear new_Rules_Weight;
            % %delete orphan fuzzy label
            % new_InTerms = [];
            % for dim = 1 : IND
            %     %delete orphan fuzzy label
            %     [Rules, Terms] = delete_orphan(Rules, InTerms(dim,:),dim);
            %     %initialise new_InTerms if this is the first dimension
            %     if dim == 1
            %         new_InTerms = [new_InTerms; Terms];
            %         no_InTerms(dim) = size(Terms,2)/2;
            %     else %for other dimension
            %         if(size(Terms,2) > size(new_InTerms,2))
            %             no_InTerms(dim) = size(Terms,2)/2;
            %             s = size(Terms,2) - size(new_InTerms,2);
            %             l = size(new_InTerms,2);
            %             for j = 1 : size(new_InTerms,1)
            %                 for k = 1 : s
            %                     new_InTerms(j,l+k) = 0;
            %                 end
            %             end
            %             new_InTerms = [new_InTerms; Terms];
            %         else
            %             no_InTerms(dim) = size(Terms,2)/2;
            %             s = size(new_InTerms,2) - size(Terms,2);
            %             l = size(Terms,2);
            %             for j = 1 : s
            %                 Terms(1,l+j) = 0;
            %             end
            %             new_InTerms = [new_InTerms; Terms];
            %         end
            %     end
            %     clear s; clear l; clear Terms;
            % end
            % InTerms = new_InTerms;
            % clear new_InTerms;
            % new_OutTerms = [];
            % for dim = 1 : OUTD
            %     [Rules, Terms] = delete_orphan(Rules, OutTerms(dim,:),IND+dim);
            %     if dim == 1
            %         new_OutTerms = [new_OutTerms; Terms];
            %         no_OutTerms(dim) = size(Terms,2)/2;
            %     else
            %         if(size(Terms,2) > size(new_OutTerms,2))
            %             no_OutTerms(dim) = size(Terms,2)/2;
            %             s = size(Terms,2) - size(new_OutTerms,2);
            %             l = size(new_OutTerms,2);
            %             for j = 1 : size(new_OutTerms,1)
            %                 for k = 1 : s
            %                     new_OutTerms(j,l+k) = 0;
            %                 end
            %             end
            %             new_OutTerms = [new_OutTerms, Terms];
            %         else
            %             no_OutTerms(dim) = size(Terms,2)/2;
            %             s = size(new_OutTerms,2) - size(Terms,2);
            %             l = size(Terms,2);
            %             for j = 1 : s
            %                 Terms(1,l+j) = 0;
            %             end
            %             new_OutTerms = [new_OutTerms, Terms];
            %         end
            %     end
            %     clear s; clear l; clear Terms;
            % end
            % OutTerms = new_OutTerms;
            % clear new_OutTerms;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%% 4. Forward propagation
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Initialize
            in_mf = zeros(IND,max(no_InTerms)); %IND x Number of Fuzzy Terms
            act_rules = zeros(size(Rules,1),1);
            inferred = zeros(OUTD,max(no_OutTerms));
            check = zeros(OUTD,max(no_OutTerms));
            net_out = zeros(OUTD,1);
            % Layer 2
            for j = 1:IND %for each dimension
                for k = 1:no_InTerms(j) %for each term in the dimension
                    in_mf(j,k) = exp( -(input(1,j)-InTerms(j,2*k-1))^2 / (InTerms(j,2*k)^2) ); %compute membership function for that term
                end
            end
            % Layer 3
            for j = 1:size(Rules,1) %for each rule
                tmp = zeros(IND,1);
                for k = 1:IND %get membership function of every antecedent
                    tmp(k) = in_mf(k,Rules(j,k));
                end
                act_rules(j) = min(tmp); %get firing rate of the rule = MIN of antecedent
                clear tmp;
            end
            % Layer 4
            for j = 1:size(Rules,1) %for each rule
                for k = 1:OUTD %for each dimension
                    if inferred(k,Rules(j,IND+k)) < act_rules(j)
                        inferred(k,Rules(j,IND+k)) = act_rules(j); %get maximum activation for term
                    end
                    check(k,Rules(j,IND+k)) = 1;
                end
            end
            % Layer 5 %compute the centroid of the system
            for j = 1:OUTD
                top = 0; bottom = 0;
                for k = 1:no_OutTerms(j)
                    if check(j,k) == 1
                        top = top + inferred(j,k)*OutTerms(j,2*k-1)*OutTerms(j,2*k);
                        bottom = bottom + inferred(j,k)*OutTerms(j,2*k);
                    end
                end
                net_out(j) = top/bottom;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 5.Backward propagation
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Initialize
            delta_5 = zeros(OUTD,1);
            delta_4 = zeros(OUTD,max(no_OutTerms));
            delta_3 = zeros(size(Rules,1),1);
            delta_2 = zeros(IND,max(no_InTerms));
            % Layer 5
            for j = 1:OUTD
                delta_5(j) = output(1,j)-net_out(j); %compute error of each term
            end
            % Layer 4
            for j = 1:OUTD
                N_m = 0; D_m = 0;
                for k = 1:no_OutTerms(j)
                    if check(j,k) == 1
                        N_m = N_m + inferred(j,k)*OutTerms(j,2*k-1)*OutTerms(j,2*k);
                        D_m = D_m + inferred(j,k)*OutTerms(j,2*k);
                    end
                end
                for k = 1:no_OutTerms(j)
                    if check(j,k) == 1
                        delta_4(j,k) = delta_5(j)*OutTerms(j,2*k)*(D_m*OutTerms(j,2*k-1)-N_m)/(D_m^2);
                        new_c = OutTerms(j,2*k-1) + Eta*delta_5(j)*OutTerms(j,2*k)*inferred(j,k)/D_m; %compute new centre
                        new_sigma = OutTerms(j,2*k) + Eta*delta_5(j)*inferred(j,k)*(D_m*OutTerms(j,2*k-1)-N_m)/(D_m^2); %compute new sigma
                        OutTerms(j,2*k-1) = new_c;
                        OutTerms(j,2*k) = new_sigma;
                    end
                end
            end
            % Layer 3
            for j = 1:size(Rules,1)
                for k = 1:OUTD
                    if act_rules(j) == inferred(k,Rules(j,IND+k))
                        delta_3(j) = delta_3(j) + delta_4(k,Rules(j,IND+k));
                    end
                end
            end
            clear check;
            check = zeros(IND,max(no_InTerms));
            % Layer 2
            for j = 1:size(Rules,1)
                for k = 1:IND
                    check(k,Rules(j,k)) = 1;
                    if act_rules(j) == in_mf(k,Rules(j,k))
                        delta_2(k,Rules(j,k)) = delta_2(k,Rules(j,k)) + delta_3(j);
                    end
                end
            end
            for j = 1:IND
                for k = 1:no_InTerms(j)
                    if check(j,k) == 1
                        new_c = InTerms(j,2*k-1) + Eta*delta_2(j,k)*in_mf(j,k)*2/(InTerms(j,2*k)^2)*(input(1,j)-InTerms(j,2*k-1));
                        new_sigma = InTerms(j,2*k) + Eta*delta_2(j,k)*in_mf(j,k)*2*(input(1,j)-InTerms(j,2*k-1))^2/(InTerms(j,2*k)^3);
                        InTerms(j,2*k-1) = new_c;
                        InTerms(j,2*k) = new_sigma;
                    end
                end
            end
            net.no_InTerms = no_InTerms;
            net.InTerms = InTerms;
            net.no_OutTerms = no_OutTerms;
            net.OutTerms = OutTerms;
            net.Rules = Rules;
            net.Rules_Weight = Rules_Weight;
            net.in_mf = in_mf;
            net.out_mf = out_mf;
            net.act_rules_forward = act_rules_forward;
            net.act_rules_backward = act_rules_backward;
            net.inferred = inferred;
        end
        
    end
    

    methods (Access = private)

        % Value changed function: cknEdge
        function cknEdgeValueChanged(app, event)
            app.alpha = app.cknEdge.Value;
            
        end

        % Value changed function: cknWindow
        function cknWindowValueChanged(app, event)
            app.beta = app.cknWindow.Value;
        end

        % Value changed function: cknWindow_2
        function cknWindow_2ValueChanged(app, event)
            app.eta = app.cknWindow_2.Value;
        end

        % Value changed function: cknLowPass
        function cknLowPassValueChanged(app, event)
            app.ff = app.cknLowPass.Value;
        end

        % Value changed function: cknHighPass
        function cknHighPassValueChanged(app, event)
            app.lamda = app.cknHighPass.Value;
            
        end

        % Value changed function: cknHighPass_2
        function cknHighPass_2ValueChanged(app, event)
            app.rate = app.cknHighPass_2.Value;
        end

        % Value changed function: cknLowPass_2
        function cknLowPass_2ValueChanged(app, event)
            app.omega = app.cknLowPass_2.Value;
        end

        % Value changed function: cknLowPass_3
        function cknLowPass_3ValueChanged(app, event)
            app.gamma = app.cknLowPass_3.Value;
        end

        % Callback function
        function EpochListBoxValueChanged(app, event)
            app.epoch = app.EpochListBox.Value;
        end

        % Value changed function: DefaultConfigurationButton
        function DefaultConfigurationButtonValueChanged(app, event)
            app.cknEdge.Value = 0.25;
            app.cknWindow.Value = 0.65;
            app.cknWindow_2.Value = 0.05;
            app.cknLowPass.Value = 0.99;
            app.cknHighPass.Value = 0.45;
            app.cknHighPass_2.Value = 0.25;
            app.cknLowPass_2.Value = 0.7;
            app.cknLowPass_3.Value = 0.1;
            app.cknLowPass_4.Value = 100;
        end

        % Value changed function: cknLowPass_4
        function cknLowPass_4ValueChanged(app, event)
            app.epoch = app.cknLowPass_4.Value;
        end

        % Value changed function: INDSpinner
        function INDSpinnerValueChanged(app, event)
            app.IND = app.INDSpinner.Value;
            %app.indata = app.filename(:,1:app.IND);
        end

        % Value changed function: OUTDSpinner
        function OUTDSpinnerValueChanged(app, event)
            app.OUTD = app.OUTDSpinner.Value;
            %app.outdata = app.filename(:,(app.IND):(app.IND+app.OUTD));
        end

        % Callback function
        function Dataset1EditFieldValueChanged(app, event)
            file = app.Dataset1EditField.Value;
            app.data = load(file);
        end

        % Value changed function: swAutoUpdate
        function swAutoUpdateValueChanged(app, event)
            app.interpolation = app.swAutoUpdate.Value;
            if(strcmp(app.interpolation,'off'))
                app.lamda = 0;
                app.lmpAutoUpdate_3.Color = 'red';
            else
                app.lamda = app.cknHighPass.Value;
                app.lmpAutoUpdate_3.Color = 'green';
            end
        end

        % Value changed function: swAutoUpdate_2
        function swAutoUpdate_2ValueChanged(app, event)
            app.episodic = app.swAutoUpdate_2.Value;
            if(strcmp(app.episodic,'off'))
                app.lmpAutoUpdate_2.Color = 'red';
            else
                app.lmpAutoUpdate_2.Color = 'green';
            end
        end

        % Callback function
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
        end

        % Callback function
        function OptionData2EditFieldValueChanged(app, event)
            link2 = app.OptionData2EditField.Value;
            app.optiondata = open(link2);
        end

        % Button pushed function: btnPlot
        function btnPlotButtonPushed(app, event)
            train_data = app.filename;
            test_data = app.filename;
            app.datasize = size(app.data,1);
            app.predicted = Run_SaFIN(2,train_data,test_data,app.IND,app.OUTD,app.epoch,app.eta,app.Sigma0,app.ff,app.lamda,app.rate,app.omega,app.gamma);
            disp(app.predicted);
        end

        % Value changed function: DatasetEditField
        function DatasetEditFieldValueChanged(app, event)
            link1 = app.DatasetEditField.Value;
            load(link1);
            for i = 1 : length(link1)
                %iterate until encountering '.'
                if(strcmp(link1(i),'.'))
                    break;
                else
                    app.filename = strcat(app.filename,link1(i));
                end
            end
            fn = app.filename;
        end

        % Value changed function: OptionDatasetEditField
        function OptionDatasetEditFieldValueChanged(app, event)
            link2 = app.OptionDatasetEditField.Value;
            app.optiondata = load(link2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.axPulsePlot = uiaxes(app.UIFigure);
            xlabel(app.axPulsePlot, 'X');
            ylabel(app.axPulsePlot, 'Y');
            app.btnPlot = uibutton(app.UIFigure, 'push');
            app.btnPlot.ButtonPushedFcn = createCallbackFcn(app, @btnPlotButtonPushed, true);
            app.btnPlot.Text = 'Run';
            app.swAutoUpdate = uiswitch(app.UIFigure, 'slider');
            app.swAutoUpdate.Items = {'off', 'on'};
            app.swAutoUpdate.ValueChangedFcn = createCallbackFcn(app, @swAutoUpdateValueChanged, true);
            app.swAutoUpdate.Value = 'on';
            app.lblAutoUpdate = uilabel(app.UIFigure);
            app.lblAutoUpdate.Text = 'Interpolation';
            app.lblDispersion = uilabel(app.UIFigure);
            app.lblDispersion.Text = 'Rate';
            app.lblModulation = uilabel(app.UIFigure);
            app.lblModulation.Text = 'Eta';
            app.lblHighPass = uilabel(app.UIFigure);
            app.lblHighPass.Text = 'Lamda';
            app.lblWindow = uilabel(app.UIFigure);
            app.lblWindow.Text = 'Beta';
            app.lblLowPass = uilabel(app.UIFigure);
            app.lblLowPass.Text = 'Forgetting Factor';
            app.lblEdge = uilabel(app.UIFigure);
            app.lblEdge.Text = 'Alpha';
            app.ddType = uidropdown(app.UIFigure);
            app.ddType.Items = {'gaussian', 'sinc', 'square', 'triangle', 'monocycle', 'exponential', 'biexponential', 'mexican hat', 'raised cosine', 'double sinc', 'sinc squared', 'sweep'};
            app.ddType.Value = 'gaussian';
            app.lblType = uilabel(app.UIFigure);
            app.lblType.Text = 'Type';
            app.lblLowPass_2 = uilabel(app.UIFigure);
            app.lblLowPass_2.Text = 'Omega';
            app.lblLowPass_3 = uilabel(app.UIFigure);
            app.lblLowPass_3.Text = 'Gamma';
            app.IntelligentOptionTradingLabel = uilabel(app.UIFigure);
            app.IntelligentOptionTradingLabel.Text = 'Intelligent Option Trading';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TRAININGTab = uitab(app.TabGroup);
            app.TRAININGTab.Title = 'TRAINING';
            app.TRAININGTab.Units = 'pixels';
            app.TRAININGINFORMATIONLabel = uilabel(app.TRAININGTab);
            app.TRAININGINFORMATIONLabel.Text = 'TRAINING INFORMATION';
            app.RULESEditFieldLabel = uilabel(app.TRAININGTab);
            app.RULESEditFieldLabel.Text = '# RULES';
            app.RULESEditField = uieditfield(app.TRAININGTab, 'numeric');
            app.INPUTCLUSTERSEditFieldLabel = uilabel(app.TRAININGTab);
            app.INPUTCLUSTERSEditFieldLabel.Text = '# INPUT CLUSTERS';
            app.INPUTCLUSTERSEditField = uieditfield(app.TRAININGTab, 'numeric');
            app.OUTPUTCLUSTERSEditFieldLabel = uilabel(app.TRAININGTab);
            app.OUTPUTCLUSTERSEditFieldLabel.Text = '# OUTPUT CLUSTERS';
            app.OUTPUTCLUSTERSEditField = uieditfield(app.TRAININGTab, 'numeric');
            app.EVALUATETab = uitab(app.TabGroup);
            app.EVALUATETab.Title = 'EVALUATE';
            app.EVALUATETab.Units = 'pixels';
            app.EVALUATINGINFORMATIONLabel = uilabel(app.EVALUATETab);
            app.EVALUATINGINFORMATIONLabel.Text = 'EVALUATING INFORMATION';
            app.MSEEditFieldLabel = uilabel(app.EVALUATETab);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.EVALUATETab, 'text');
            app.RMSEEditFieldLabel = uilabel(app.EVALUATETab);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.EVALUATETab, 'text');
            app.REditFieldLabel = uilabel(app.EVALUATETab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.EVALUATETab, 'text');
            app.TRADINGTab = uitab(app.TabGroup);
            app.TRADINGTab.Title = 'TRADING';
            app.TRADINGTab.Units = 'pixels';
            app.TRADINGRESULTSLabel = uilabel(app.TRADINGTab);
            app.TRADINGRESULTSLabel.Text = 'TRADING RESULTS';
            app.AVERAGEPROFITLOSSEditFieldLabel = uilabel(app.TRADINGTab);
            app.AVERAGEPROFITLOSSEditFieldLabel.Text = 'AVERAGE PROFIT/LOSS';
            app.AVERAGEPROFITLOSSEditField = uieditfield(app.TRADINGTab, 'text');
            app.OVERALLPROFITLOSSEditFieldLabel = uilabel(app.TRADINGTab);
            app.OVERALLPROFITLOSSEditFieldLabel.Text = 'OVERALL PROFIT/ LOSS';
            app.OVERALLPROFITLOSSEditField = uieditfield(app.TRADINGTab, 'numeric');
            app.NUMBEROFTRADESEditFieldLabel = uilabel(app.TRADINGTab);
            app.NUMBEROFTRADESEditFieldLabel.Text = 'NUMBER OF TRADES';
            app.NUMBEROFTRADESEditField = uieditfield(app.TRADINGTab, 'numeric');
            app.DAILYRETURNEditFieldLabel = uilabel(app.TRADINGTab);
            app.DAILYRETURNEditFieldLabel.Text = 'DAILY RETURN';
            app.DAILYRETURNEditField = uieditfield(app.TRADINGTab, 'numeric');
            app.INTERPRETTab = uitab(app.TabGroup);
            app.INTERPRETTab.Title = 'INTERPRET';
            app.INTERPRETTab.Units = 'pixels';
            app.INTERPREATEDRULESLabel = uilabel(app.INTERPRETTab);
            app.INTERPREATEDRULESLabel.Text = 'INTERPREATED RULES';
            app.MODEButtonGroup = uibuttongroup(app.UIFigure);
            app.MODEButtonGroup.Title = 'MODE';
            app.BenchmarkButton = uiradiobutton(app.MODEButtonGroup);
            app.BenchmarkButton.Text = 'Benchmark';
            app.BenchmarkButton.Value = true;
            app.OptionTradingButton = uiradiobutton(app.MODEButtonGroup);
            app.OptionTradingButton.Text = 'Option Trading';
            app.StockTradingButton = uiradiobutton(app.MODEButtonGroup);
            app.StockTradingButton.Text = 'Stock Trading';
            app.lmpAutoUpdate_2 = uilamp(app.UIFigure);
            app.swAutoUpdate_2 = uiswitch(app.UIFigure, 'slider');
            app.swAutoUpdate_2.Items = {'off', 'on'};
            app.swAutoUpdate_2.ValueChangedFcn = createCallbackFcn(app, @swAutoUpdate_2ValueChanged, true);
            app.swAutoUpdate_2.Value = 'on';
            app.lblAutoUpdate_2 = uilabel(app.UIFigure);
            app.lblAutoUpdate_2.Text = 'Episodic Memory';
            app.INDSpinnerLabel = uilabel(app.UIFigure);
            app.INDSpinnerLabel.Text = 'IND';
            app.INDSpinner = uispinner(app.UIFigure);
            app.INDSpinner.ValueChangedFcn = createCallbackFcn(app, @INDSpinnerValueChanged, true);
            app.INDSpinner.Limits = [2 Inf];
            app.INDSpinner.Value = 4;
            app.OUTDSpinnerLabel = uilabel(app.UIFigure);
            app.OUTDSpinnerLabel.Text = 'OUTD';
            app.OUTDSpinner = uispinner(app.UIFigure);
            app.OUTDSpinner.ValueChangedFcn = createCallbackFcn(app, @OUTDSpinnerValueChanged, true);
            app.OUTDSpinner.Value = 1;
            app.cknWindow_2 = uiknob(app.UIFigure, 'continuous');
            app.cknWindow_2.Limits = [0.05 1];
            app.cknWindow_2.MajorTicks = [0.05 0.15 0.25 0.35 0.45 0.55 0.65 0.75 0.85 1];
            app.cknWindow_2.ValueChangedFcn = createCallbackFcn(app, @cknWindow_2ValueChanged, true);
            app.cknWindow_2.Value = 0.05;
            app.cknHighPass = uiknob(app.UIFigure, 'continuous');
            app.cknHighPass.Limits = [0 1];
            app.cknHighPass.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.cknHighPass.ValueChangedFcn = createCallbackFcn(app, @cknHighPassValueChanged, true);
            app.cknHighPass.Value = 0.4;
            app.cknWindow = uiknob(app.UIFigure, 'continuous');
            app.cknWindow.Limits = [0.4 1];
            app.cknWindow.ValueChangedFcn = createCallbackFcn(app, @cknWindowValueChanged, true);
            app.cknWindow.Value = 0.65;
            app.cknLowPass = uiknob(app.UIFigure, 'continuous');
            app.cknLowPass.Limits = [0.5 0.99];
            app.cknLowPass.ValueChangedFcn = createCallbackFcn(app, @cknLowPassValueChanged, true);
            app.cknLowPass.Value = 0.99;
            app.cknEdge = uiknob(app.UIFigure, 'continuous');
            app.cknEdge.Limits = [0 4];
            app.cknEdge.ValueChangedFcn = createCallbackFcn(app, @cknEdgeValueChanged, true);
            app.cknEdge.Value = 1;
            app.cknLowPass_2 = uiknob(app.UIFigure, 'continuous');
            app.cknLowPass_2.Limits = [0.1 1];
            app.cknLowPass_2.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.cknLowPass_2.ValueChangedFcn = createCallbackFcn(app, @cknLowPass_2ValueChanged, true);
            app.cknLowPass_2.Value = 0.7;
            app.cknLowPass_3 = uiknob(app.UIFigure, 'continuous');
            app.cknLowPass_3.Limits = [0 1];
            app.cknLowPass_3.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.cknLowPass_3.ValueChangedFcn = createCallbackFcn(app, @cknLowPass_3ValueChanged, true);
            app.cknLowPass_3.Value = 0.1;
            app.DefaultConfigurationButton = uibutton(app.UIFigure, 'state');
            app.DefaultConfigurationButton.ValueChangedFcn = createCallbackFcn(app, @DefaultConfigurationButtonValueChanged, true);
            app.DefaultConfigurationButton.Text = 'Default Configuration';
            app.cknHighPass_2 = uiknob(app.UIFigure, 'continuous');
            app.cknHighPass_2.Limits = [0.1 0.5];
            app.cknHighPass_2.ValueChangedFcn = createCallbackFcn(app, @cknHighPass_2ValueChanged, true);
            app.cknHighPass_2.Value = 0.25;
            app.cknLowPass_4 = uiknob(app.UIFigure, 'continuous');
            app.cknLowPass_4.Limits = [0 500];
            app.cknLowPass_4.ValueChangedFcn = createCallbackFcn(app, @cknLowPass_4ValueChanged, true);
            app.cknLowPass_4.Value = 1;
            app.lblLowPass_4 = uilabel(app.UIFigure);
            app.lblLowPass_4.Text = 'Epoch';
            app.DatasetEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.DatasetEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetEditFieldValueChanged, true);
            app.OptionDatasetEditFieldLabel = uilabel(app.UIFigure);
            app.OptionDatasetEditFieldLabel.Text = 'Option Dataset';
            app.OptionDatasetEditField = uieditfield(app.UIFigure, 'text');
            app.OptionDatasetEditField.ValueChangedFcn = createCallbackFcn(app, @OptionDatasetEditFieldValueChanged, true);
            app.lmpAutoUpdate_3 = uilamp(app.UIFigure);
            app.lmpAutoUpdate_4 = uilamp(app.UIFigure);
            app.swAutoUpdate_3 = uiswitch(app.UIFigure, 'slider');
            app.swAutoUpdate_3.Items = {'off', 'on'};
            app.swAutoUpdate_3.Value = 'on';
            app.lblAutoUpdate_3 = uilabel(app.UIFigure);
            app.lblAutoUpdate_3.Text = 'Forgetting';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptionTradingApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end